classdef BEAMS3Dplot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ParticleSlider           matlab.ui.control.Slider
        ParticleSliderLabel      matlab.ui.control.Label
        TimestepSlider           matlab.ui.control.Slider
        TimestepSliderLabel      matlab.ui.control.Label
        PopulationBox            matlab.ui.control.ListBox
        StatusLabel              matlab.ui.control.Label
        PlotWallCheckBox         matlab.ui.control.CheckBox
        PlotEquilibriumCheckBox  matlab.ui.control.CheckBox
        Label3                   matlab.ui.control.Label
        Label2                   matlab.ui.control.Label
        Label1                   matlab.ui.control.Label
        Slider3                  matlab.ui.control.Slider
        Slider2                  matlab.ui.control.Slider
        EditField                matlab.ui.control.EditField
        PlottoFileButton         matlab.ui.control.Button
        CutsButtonGroup          matlab.ui.container.ButtonGroup
        VLLVPERPButton           matlab.ui.control.RadioButton
        RHOUButton               matlab.ui.control.RadioButton
        RHOButton                matlab.ui.control.RadioButton
        XYZButton                matlab.ui.control.RadioButton
        PHIZButton               matlab.ui.control.RadioButton
        PHIRButton               matlab.ui.control.RadioButton
        RZButton                 matlab.ui.control.RadioButton
        ZButton                  matlab.ui.control.RadioButton
        PHIButton                matlab.ui.control.RadioButton
        RButton                  matlab.ui.control.RadioButton
        PlotDropDown             matlab.ui.control.DropDown
        PlotDropDownLabel        matlab.ui.control.Label
        Slider1                  matlab.ui.control.Slider
        BEAMS3DplotCreatedbySamuelLazersonsamuellazersonippmpgdeLabel  matlab.ui.control.Label
        FilenameDropDown         matlab.ui.control.DropDown
        FilenameDropDownLabel    matlab.ui.control.Label
        plotAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        fp_wall % Field period reduction of wall model
        beam_data % Current BEAMS3D data
        raxis_spl % Axis spline
        zaxis_spl % Axis spline
        back_fields = {'|B|','B_R','B_PHI','B_Z'} % Basic background grids
        opt_fields = {'TE','NE','TI','ZEFF_ARR','POT_ARR','U_ARR',...
            'E_R','E_PHI','E_Z','S_ARR','RHO'} % Optional Quantities
        orbit_fields = {'MARKERS','ORBITS','PARTICLES'}
        special_fields = {'LOSS','EVOLUTION'};
        dist_fields = {'dense_prof','j_prof','epower_prof','ipower_prof','birth_prof','loss_prof'}
        wall_fields = {'WALL3D','WALL_MAGAXIS'}
        val_label ='none'
        plot_title = ''
        cut_type = 'R' % Which cut is selected
        slide_pos1 = 1
        slide_pos2 = 1
        slide_pos3 = 1
        slide_pos4 = 1
        slide_pos5 = 1
        poplist = [];
        maxv
    end
    
    methods (Access = private)
        
        function update_plotlist(app)
            % Update plots accordingly
            app.PlotDropDown.Items={'Overview','---BACKGROUND---'};
            app.PlotDropDown.Items=[app.PlotDropDown.Items app.back_fields];
            for i=1:length(app.opt_fields)
                if isfield(app.beam_data,app.opt_fields{i})
                    app.PlotDropDown.Items = [app.PlotDropDown.Items app.opt_fields{i}];
                end
            end
            app.PlotDropDown.Items=[app.PlotDropDown.Items '---ORBITS---'];
            app.PlotDropDown.Items=[app.PlotDropDown.Items app.orbit_fields];
            app.PlotDropDown.Items=[app.PlotDropDown.Items '---DIST---'];
            app.PlotDropDown.Items=[app.PlotDropDown.Items app.dist_fields];
            app.PlotDropDown.Items=[app.PlotDropDown.Items '---SPECIAL---'];
            if ~app.beam_data.ldepo
                app.PlotDropDown.Items=[app.PlotDropDown.Items app.special_fields];
            end
            if isfield(app.beam_data,'wall_vertex')
                app.PlotDropDown.Items=[app.PlotDropDown.Items '---WALL---'];
                app.PlotDropDown.Items=[app.PlotDropDown.Items app.wall_fields];
                if isfield(app.beam_data,'wall_load')
                    app.PlotDropDown.Items=[app.PlotDropDown.Items 'WALL_MAGAXIS_HEATFLUX'];
                end
                if isfield(app.beam_data,'wall_shine')
                    app.PlotDropDown.Items=[app.PlotDropDown.Items 'WALL_MAGAXIS_SHINE'];
                end
                if isfield(app.beam_data,'wall_strikes')
                    app.PlotDropDown.Items=[app.PlotDropDown.Items 'WALL_MAGAXIS_STRIKES'];
                end
            end
        end
        
        function update_plots(app,plottype)
            app.RButton.Enable=1;
            app.PHIButton.Enable=1;
            app.ZButton.Enable=1;
            app.RHOButton.Enable=0;
            app.RZButton.Enable=1;
            app.PHIRButton.Enable=1;
            app.PHIZButton.Enable=1;
            app.RHOUButton.Enable=0;
            app.XYZButton.Enable=1;
            app.VLLVPERPButton.Enable=0;
            camproj(app.plotAxes,'orthographic');
            legend(app.plotAxes,'off')
            switch plottype
                case{'Overview'}
                    colorbar(app.plotAxes,'off');
                    rotate3d(app.plotAxes,'off');
                    zoom(app.plotAxes,'off');
                    view(app.plotAxes,2);
                    cla(app.plotAxes);
                    axis(app.plotAxes,'normal');
                    app.RButton.Enable=0;
                    app.PHIButton.Enable=0;
                    app.ZButton.Enable=0;
                    app.RHOButton.Enable=0;
                    app.RZButton.Enable=0;
                    app.PHIRButton.Enable=0;
                    app.PHIZButton.Enable=0;
                    app.RHOUButton.Enable=0;
                    app.XYZButton.Enable=0;
                    app.VLLVPERPButton.Enable=0;
                    text(app.plotAxes,0.05,0.95,['FILENAME:  ' strrep(app.FilenameDropDown.Value,'_','\_')])
                    text(app.plotAxes,0.05,0.90,'GRID PARAMETERS')
                    text(app.plotAxes,0.10,0.87,['R   = [' num2str(min(app.beam_data.raxis),'%5.2f') ','...
                        num2str(max(app.beam_data.raxis),'%5.2f') ']'])
                    text(app.plotAxes,0.10,0.83,['PHI = [' num2str(min(app.beam_data.phiaxis),'%5.2f') ','...
                        num2str(max(app.beam_data.phiaxis),'%5.2f') ']'])
                    text(app.plotAxes,0.10,0.79,['Z   = [' num2str(min(app.beam_data.zaxis),'%5.2f') ','...
                        num2str(max(app.beam_data.zaxis),'%5.2f') ']'])
                    text(app.plotAxes,0.10,0.75,['[ ' num2str(app.beam_data.nr,'%i') ' x ' num2str(app.beam_data.nphi,'%i') ' x ' num2str(app.beam_data.nz,'%i') ' ]'])
                    if (app.beam_data.lvmec)
                        text(app.plotAxes,0.10,0.71,'VMEC Equilibrium')
                    elseif (app.beam_data.lspec)
                        text(app.plotAxes,0.10,0.71,'SPEC Equilibrium')
                    elseif (app.beam_data.lpies)
                        text(app.plotAxes,0.10,0.71,'PIES Equilibrium')
                    elseif (app.beam_data.lvac)
                        text(app.plotAxes,0.10,0.71,'Vacuum Equilibrium')
                    end
                    text(app.plotAxes,0.05,0.65,'PROFILE PARAMETERS')
                    if isfield(app.beam_data,'NE')
                        text(app.plotAxes,0.10,0.61,['NE   = [' num2str(min(app.beam_data.ne1d).*1E-19,'%5.2f') ','...
                            num2str(max(app.beam_data.ne1d).*1E-19,'%5.2f') '] x10^{19} m^{-3}'])
                        text(app.plotAxes,0.10,0.57,['TE   = [' num2str(min(app.beam_data.te1d).*1E-3,'%5.2f') ','...
                            num2str(max(app.beam_data.te1d).*1E-3,'%5.2f') '] keV'])
                        text(app.plotAxes,0.10,0.53,['TI   = [' num2str(min(app.beam_data.ti1d).*1E-3,'%5.2f') ','...
                            num2str(max(app.beam_data.ti1d).*1E-3,'%5.2f') '] keV'])
                        text(app.plotAxes,0.10,0.49,['ZEFF = [' num2str(min(app.beam_data.zeff1d),'%5.2f') ','...
                            num2str(max(app.beam_data.zeff1d),'%5.2f') ']'])
                    else
                        text(app.plotAxes,0.10,0.61,'Collisionless Run')
                    end
                    text(app.plotAxes,0.05,0.40,'E-FIELD PARAMETERS')
                    text(app.plotAxes,0.10,0.36,['V = [' num2str(min(app.beam_data.pot1d).*1E-3,'%5.2f') ','...
                        num2str(max(app.beam_data.pot1d.*1E-3),'%5.2f') '] kV'])
                    if isfield(app.beam_data,'nparticles')
                        text(app.plotAxes,0.55,0.90,'PARTICLE POPULATION')
                        text(app.plotAxes,0.60,0.87,['N    = ' num2str(app.beam_data.nparticles,'%i')])
                        text(app.plotAxes,0.60,0.83,['NT   = ' num2str(app.beam_data.npoinc+1,'%i')])
                        text(app.plotAxes,0.60,0.79,['NPOP = ' num2str(max(app.beam_data.Beam),'%i')])
                        if double(app.beam_data.lbeam)==1
                            text(app.plotAxes,0.60,0.75,'Neutral Beam Injection')
                        elseif double(app.beam_data.lfusion)==1
                            text(app.plotAxes,0.60,0.75,'Nuclear Fusion Births')
                        else
                            text(app.plotAxes,0.60,0.75,'User supplied / restart')
                        end
                    else
                        text(app.plotAxes,0.55,0.90,'NO PARTICLES IN FILE')
                    end
                    text(app.plotAxes,0.55,0.65,'WALL MODEL')
                    if isfield(app.beam_data,'wall_faces')
                        text(app.plotAxes,0.60,0.61,['NFACES  = ' num2str(size(app.beam_data.wall_faces,1),'%i')])
                        text(app.plotAxes,0.60,0.57,['NVERTEX = ' num2str(size(app.beam_data.wall_vertex,1),'%i')])
                    else
                        text(app.plotAxes,0.60,0.61,'No Wall Found')
                    end
                    xlim(app.plotAxes,[0 1]);
                    ylim(app.plotAxes,[0 1]);
                    xlabel(app.plotAxes,'')
                    ylabel(app.plotAxes,'')
                case{'|B|'}
                    plotdata = app.beam_data.B;
                    app.val_label='|B| T';
                    app.plot_title= 'Magnetic Induction';
                    app.plot_cut(plotdata);
                case{'B_R','B_PHI','B_Z'}
                    plotdata = app.beam_data.(plottype);
                    app.val_label='B T';
                    app.plot_title= ['Magnetic Induction (' strrep(plottype,'_','_{') '})'];
                    app.plot_cut(plotdata);
                case{'E_R','E_PHI','E_Z'}
                    plotdata = app.beam_data.(plottype);
                    app.val_label='E V/m';
                    app.plot_title= ['Electric field (' strrep(plottype,'_','_{') '})'];
                    app.plot_cut(plotdata);
                case{'TE'}
                    app.RHOButton.Enable=1;
                    plotdata = app.beam_data.(plottype).*1E-3;
                    app.val_label='Temperature [keV]';
                    app.plot_title= 'Electron Temperature';
                    app.plot_cut(plotdata);
                case{'TI'}
                    app.RHOButton.Enable=1;
                    plotdata = app.beam_data.(plottype).*1E-3;
                    app.val_label='Temperature [keV]';
                    app.plot_title= 'Ion Temperature';
                    app.plot_cut(plotdata);
                case{'NE'}
                    app.RHOButton.Enable=1;
                    plotdata = app.beam_data.(plottype).*1E-19;
                    app.val_label='Density x10^{19} [m^{-3}]';
                    app.plot_title= 'Electron Density';
                    app.plot_cut(plotdata);
                case{'ZEFF_ARR'}
                    app.RHOButton.Enable=1;
                    plotdata = app.beam_data.(plottype);
                    app.val_label='Z_{effective}';
                    app.plot_title= 'Effective Ion Charge Number';
                    app.plot_cut(plotdata);
                case{'POT_ARR'}
                    app.RHOButton.Enable=1;
                    plotdata = app.beam_data.(plottype).*1E-3;
                    app.val_label='E-Static Potential [kV]';
                    app.plot_title= 'Electrostatic Potential';
                    app.plot_cut(plotdata);
                case{'S_ARR'}
                    plotdata = app.beam_data.(plottype);
                    app.val_label='Normalized Toroidal Flux (s)';
                    app.plot_title= 'Normalized Toroidal Flux';
                    app.plot_cut(plotdata);
                case{'U_ARR'}
                    plotdata = app.beam_data.(plottype);
                    app.val_label='Eq. Poloidal Angle (\theta) [rad]';
                    app.plot_title= 'Equilibrium Poloidal Angle';
                    app.plot_cut(plotdata);
                case{'RHO'}
                    plotdata = sqrt(app.beam_data.S_ARR);
                    app.val_label='Normalized Radius (r/a)';
                    app.plot_title= 'Normalized Radius';
                    app.plot_cut(plotdata);
                case{'MARKERS'}
                    app.RButton.Enable=0;
                    app.PHIButton.Enable=0;
                    app.ZButton.Enable=0;
                    app.RHOButton.Enable=1;
                    app.RZButton.Enable=1;
                    app.PHIRButton.Enable=0;
                    app.PHIZButton.Enable=0;
                    app.RHOUButton.Enable=1;
                    app.XYZButton.Enable=1;
                    app.VLLVPERPButton.Enable=1;
                    app.val_label='';
                    app.plot_title= 'Marker Position';
                    app.plot_cut_markers;
                case{'PARTICLES'}
                    app.RButton.Enable=0;
                    app.PHIButton.Enable=0;
                    app.ZButton.Enable=0;
                    app.RHOButton.Enable=1;
                    app.RZButton.Enable=0;
                    app.PHIRButton.Enable=0;
                    app.PHIZButton.Enable=0;
                    app.RHOUButton.Enable=1;
                    app.XYZButton.Enable=0;
                    app.VLLVPERPButton.Enable=1;
                    app.val_label='';
                    app.plot_title= 'Particle Position';
                    app.plot_cut_particles;
                case{'ORBITS'}
                    app.RButton.Enable=0;
                    app.PHIButton.Enable=0;
                    app.ZButton.Enable=0;
                    app.RHOButton.Enable=1;
                    app.RZButton.Enable=1;
                    app.PHIRButton.Enable=0;
                    app.PHIZButton.Enable=0;
                    app.RHOUButton.Enable=1;
                    app.XYZButton.Enable=1;
                    app.VLLVPERPButton.Enable=1;
                    app.val_label='';
                    app.plot_title= 'Particle Orbits';
                    app.plot_cut_orbits;
                case{'EVOLUTION'}
                    app.RButton.Enable=0;
                    app.PHIButton.Enable=0;
                    app.ZButton.Enable=0;
                    app.RHOButton.Enable=0;
                    app.RZButton.Enable=0;
                    app.PHIRButton.Enable=0;
                    app.PHIZButton.Enable=0;
                    app.RHOUButton.Enable=0;
                    app.XYZButton.Enable=0;
                    app.VLLVPERPButton.Enable=0;
                    app.val_label='';
                    app.plot_title= 'Marker Evolution';
                    app.plot_cut_evoltuion;
                case{'LOSS'}
                    app.RButton.Enable=0;
                    app.PHIButton.Enable=0;
                    app.ZButton.Enable=0;
                    app.RHOButton.Enable=0;
                    app.RZButton.Enable=0;
                    app.PHIRButton.Enable=0;
                    app.PHIZButton.Enable=0;
                    app.RHOUButton.Enable=0;
                    app.XYZButton.Enable=0;
                    app.VLLVPERPButton.Enable=0;
                    app.val_label='';
                    app.plot_title= 'Lost Markers';
                    app.plot_cut_loss;
                case{'dense_prof','j_prof','ipower_prof','epower_prof','birth_prof','loss_prof'}
                    app.RButton.Enable=0; app.RButton.Value=0;
                    app.PHIButton.Enable=0; app.PHIButton.Value=0;
                    app.ZButton.Enable=0; app.ZButton.Value=0;
                    app.RHOButton.Enable=1; app.RHOButton.Value=1;
                    app.RZButton.Enable=0; app.RZButton.Value=0;
                    app.PHIRButton.Enable=0; app.PHIRButton.Value=0;
                    app.PHIZButton.Enable=0; app.PHIZButton.Value=0;
                    app.RHOUButton.Enable=0; app.RHOUButton.Value=0;
                    app.XYZButton.Enable=0; app.XYZButton.Value=0;
                    app.VLLVPERPButton.Enable=0; app.VLLVPERPButton.Value=0;
                    app.Slider1.Enable=0; app.Slider2.Enable=0; app.Slider3.Enable=0;
                    app.cut_type='RHO';
                    plotdata = app.beam_data.(plottype);
                    switch plottype
                        case{'dense_prof'}
                            plotdata = plotdata.*1E-19;
                            app.val_label='Density x10^{19} [m^{-3}]';
                            app.plot_title= 'Fast Ion Density';
                        case{'j_prof'}
                            if max(max(abs(plotdata)))> 1E6
                                app.val_label='Current Density [MA/m^2]';
                                plotdata = plotdata.*1E-6;
                            elseif max(max(abs(plotdata))) > 1E3
                                app.val_label='Current Density [kA/m^2]';
                                plotdata = plotdata.*1E-3;
                            else
                                app.val_label='Current Density [A/m^2]';
                            end
                            app.plot_title= 'Fast Ion Current Density';
                        case{'epower_prof'}
                            if max(max(plotdata))> 1E6
                                app.val_label='Deposited Power Density [MW/m^3]';
                                plotdata = plotdata.*1E-6;
                            elseif max(max(plotdata)) > 1E3
                                app.val_label='Deposited Power Density [kW/m^3]';
                                plotdata = plotdata.*1E-3;
                            else
                                app.val_label='Deposited Power Density [W/m^3]';
                            end
                            app.plot_title= 'Electron Heating';
                        case{'ipower_prof'}
                            if max(max(plotdata))> 1E6
                                app.val_label='Deposited Power Density [MW/m^3]';
                                plotdata = plotdata.*1E-6;
                            elseif max(max(plotdata)) > 1E3
                                app.val_label='Deposited Power Density [kW/m^3]';
                                plotdata = plotdata.*1E-3;
                            else
                                app.val_label='Deposited Power Density [W/m^3]';
                            end
                            app.plot_title= 'Ion Heating';
                        case{'birth_prof'}
                            plotdata = plotdata.*1E-19;
                            app.val_label='Birth Rate x10^{19} [m^{-3}/s]';
                            app.plot_title= 'Fast Ion Birth Rate';
                        case{'loss_prof'}
                            plotdata = plotdata.*1E-19;
                            app.val_label='Loss Rate x10^{19} [m^{-3}/s]';
                            app.plot_title= 'Fast Ion Loss Rate';
                    end
                    app.plot_cut_dist(plotdata);
                case{'WALL3D'}
                    app.RButton.Enable=0;
                    app.PHIButton.Enable=0;
                    app.ZButton.Enable=0;
                    app.RHOButton.Enable=0;
                    app.RZButton.Enable=0;
                    app.PHIRButton.Enable=0;
                    app.PHIZButton.Enable=0;
                    app.RHOUButton.Enable=0;
                    app.XYZButton.Enable=0;
                    cla(app.plotAxes);
                    p=patch(app.plotAxes,'Vertices',app.beam_data.wall_vertex,...
                        'Faces',app.beam_data.wall_faces);
                    p.FaceColor=[0.5 0.5 0.5];
                    p.EdgeColor='none';
                    p.FaceLighting = 'gouraud';
                    camlight(app.plotAxes,'headlight');
                    axis(app.plotAxes,'equal');
                    rotate3d(app.plotAxes,'on')
                    view(app.plotAxes,3);
                    zoom(app.plotAxes,'off');
                    xlabel(app.plotAxes,'');
                    ylabel(app.plotAxes,'');
                    zlabel(app.plotAxes,'');
                    title(app.plotAxes,'');
                case{'WALL_MAGAXIS'}
                    app.RButton.Enable=0;
                    app.PHIButton.Enable=0;
                    app.ZButton.Enable=0;
                    app.RHOButton.Enable=0;
                    app.RZButton.Enable=0;
                    app.PHIRButton.Enable=0;
                    app.PHIZButton.Enable=0;
                    app.RHOUButton.Enable=0;
                    app.XYZButton.Enable=0;
                    cla(app.plotAxes);
                    p=patch(app.plotAxes,'Vertices',app.beam_data.wall_vertex,...
                        'Faces',app.beam_data.wall_faces);
                    phi = app.slide_pos2.*2.*pi./100;
                    theta = app.slide_pos3;
                    alpha = app.slide_pos1;
                    r = ppval(app.raxis_spl,mod(phi,max(app.beam_data.phiaxis)));
                    z = ppval(app.zaxis_spl,mod(phi,max(app.beam_data.phiaxis)));
                    campos(app.plotAxes,[r.*cos(phi) r.*sin(phi) z]);
                    camtarget(app.plotAxes,[r.*cos(phi+0.0001) r.*sin(phi+0.0001) z])
                    campan(app.plotAxes,theta,alpha);
                    camva(app.plotAxes,60);
                    camproj(app.plotAxes,'perspective');
                    p.FaceColor=[0.5 0.5 0.5];
                    p.EdgeColor='black';
                    p.FaceLighting = 'gouraud';
                    camlight(app.plotAxes,'headlight');
                    axis(app.plotAxes,'equal');
                    axis(app.plotAxes,'off');
                    zoom(app.plotAxes,'off');
                    rotate3d(app.plotAxes,'off')
                    app.plotAxes.Clipping='on';
                    app.plotAxes.ClippingStyle='rectangle';
                    xlabel(app.plotAxes,'');
                    ylabel(app.plotAxes,'');
                    zlabel(app.plotAxes,'');
                    title(app.plotAxes,'');
                case{'WALL_MAGAXIS_HEATFLUX'}
                    app.RButton.Enable=0;
                    app.PHIButton.Enable=0;
                    app.ZButton.Enable=0;
                    app.RHOButton.Enable=0;
                    app.RZButton.Enable=0;
                    app.PHIRButton.Enable=0;
                    app.PHIZButton.Enable=0;
                    app.RHOUButton.Enable=0;
                    app.XYZButton.Enable=0;
                    cla(app.plotAxes);
                    val = sum(app.beam_data.wall_load,1)';
                    val(val>1E6) = 1E6;
                    p=patch(app.plotAxes,'Vertices',app.beam_data.wall_vertex,...
                        'Faces',app.beam_data.wall_faces,...
                        'FaceVertexCData',log10(val));
                    cmap = colormap(app.plotAxes,'hot');
                    cmap(1,:) = [0.2 0.2 0.2]; % grey
                    colormap(app.plotAxes,cmap);
                    phi = app.slide_pos2.*2.*pi./100;
                    theta = app.slide_pos3;
                    alpha = app.slide_pos1;
                    r = ppval(app.raxis_spl,mod(phi,max(app.beam_data.phiaxis)));
                    z = ppval(app.zaxis_spl,mod(phi,max(app.beam_data.phiaxis)));
                    campos(app.plotAxes,[r.*cos(phi) r.*sin(phi) z]);
                    camtarget(app.plotAxes,[r.*cos(phi+0.0001) r.*sin(phi+0.0001) z])
                    campan(app.plotAxes,theta,alpha);
                    camva(app.plotAxes,60);
                    camproj(app.plotAxes,'perspective');
                    p.EdgeColor='none';
                    %p.FaceLighting = 'gouraud';
                    p.CDataMapping = 'scaled';
                    p.FaceColor = 'flat';
                    p.AmbientStrength=1;
                    p.SpecularStrength=0.75;
                    p.DiffuseStrength=0.9;
                    p.SpecularColorReflectance=0;
                    camlight(app.plotAxes,'headlight');
                    axis(app.plotAxes,'equal');
                    axis(app.plotAxes,'off');
                    zoom(app.plotAxes,'off');
                    rotate3d(app.plotAxes,'off')
                    app.plotAxes.Clipping='on';
                    app.plotAxes.ClippingStyle='rectangle';
                    xlabel(app.plotAxes,'');
                    ylabel(app.plotAxes,'');
                    zlabel(app.plotAxes,'');
                    title(app.plotAxes,'');
            end
        end
        
        function plot_cut(app,plotdata)
            colorbar(app.plotAxes,'off');
            rotate3d(app.plotAxes,'off');
            zoom(app.plotAxes,'on');
            view(app.plotAxes,2);
            cla(app.plotAxes);
            axis(app.plotAxes,'normal');
            switch app.cut_type
                case 'R'
                    plot(app.plotAxes,app.beam_data.raxis,squeeze(plotdata(:,app.slide_pos2,app.slide_pos3)),'k','LineWidth',2)
                    xlabel(app.plotAxes,'R [m]')
                    ylabel(app.plotAxes,app.val_label)
                    title(app.plotAxes,app.plot_title)
                    axis(app.plotAxes,'tight');
                case 'PHI'
                    plot(app.plotAxes,app.beam_data.phiaxis,squeeze(plotdata(app.slide_pos1,:,app.slide_pos3)),'k','LineWidth',2)
                    xlabel(app.plotAxes,'\phi [rad]')
                    ylabel(app.plotAxes,app.val_label)
                    title(app.plotAxes,app.plot_title)
                    axis(app.plotAxes,'tight');
                case 'Z'
                    plot(app.plotAxes,app.beam_data.zaxis,squeeze(plotdata(app.slide_pos1,app.slide_pos2,:)),'k','LineWidth',2)
                    xlabel(app.plotAxes,'Z [m]')
                    ylabel(app.plotAxes,app.val_label)
                    title(app.plotAxes,app.plot_title)
                    axis(app.plotAxes,'tight');
                case 'R/Z'
                    phi = app.beam_data.phiaxis(app.slide_pos2);
                    pcolor(app.plotAxes,app.beam_data.raxis,app.beam_data.zaxis,squeeze(plotdata(:,app.slide_pos2,:))');
                    app.plotAxes.Children.EdgeColor = 'none';
                    colormap(app.plotAxes,'jet')
                    xlabel(app.plotAxes,'R [m]');
                    ylabel(app.plotAxes,'Z [m]');
                    axis(app.plotAxes,'tight');
                    title(app.plotAxes,[app.plot_title ' (\phi = ' num2str(round(rad2deg(phi)),'%3i') '^o)'])
                    caxis(app.plotAxes,[min(min(min(plotdata))) max(max(max(plotdata)))]);
                    chandle=colorbar(app.plotAxes);
                    chandle.Label.String=app.val_label;
                    if app.PlotEquilibriumCheckBox.Value
                        hold(app.plotAxes,"on");
                        s=squeeze(app.beam_data.S_ARR(:,app.slide_pos2,:))';
                        contour(app.plotAxes,app.beam_data.raxis,app.beam_data.zaxis,s,[1 1],'r','LineWidth',2)
                        contour(app.plotAxes,app.beam_data.raxis,app.beam_data.zaxis,s,[0.25 0.25],'--r')
                        hold(app.plotAxes,'off');
                    end
                    if app.PlotWallCheckBox.Value
                        surf1=[]; surf2=[];
                        surf1.vertices=app.beam_data.wall_vertex';
                        surf1.faces=app.beam_data.wall_faces';
                        rmin = min(app.beam_data.raxis); rmax = max(app.beam_data.raxis);
                        zmin = min(app.beam_data.zaxis); zmax = max(app.beam_data.zaxis);
                        surf2.vertices=[rmin.*cos(phi) rmin.*sin(phi) zmax; rmax.*cos(phi) rmax.*sin(phi) zmax;...
                            rmin.*cos(phi) rmin.*sin(phi) zmin; rmax.*cos(phi) rmax.*sin(phi) zmin];
                        surf2.faces = [1 2 3; 3 2 4];
                        [~, intSurface] = SurfaceIntersection(surf1, surf2);
                        x2 = intSurface.vertices(:,1);
                        y2 = intSurface.vertices(:,2);
                        z2 = intSurface.vertices(:,3);
                        r2 = sqrt(x2.*x2+y2.*y2);
                        hold(app.plotAxes,"on");
                        plot(app.plotAxes,r2(intSurface.edges'),z2(intSurface.edges'),'w')
                        hold(app.plotAxes,"off");
                    end
                case 'PHI/R'
                    z = app.beam_data.zaxis(app.slide_pos3);
                    pcolor(app.plotAxes,app.beam_data.raxis,app.beam_data.phiaxis,squeeze(plotdata(:,:,app.slide_pos3))');
                    app.plotAxes.Children.EdgeColor = 'none';
                    colormap(app.plotAxes,'jet')
                    xlabel(app.plotAxes,'R [m]');
                    ylabel(app.plotAxes,'\phi [rad]');
                    title(app.plotAxes,[app.plot_title ' (Z = ' num2str(z,'%5.2f') ' m)'])
                    caxis(app.plotAxes,[min(min(min(plotdata))) max(max(max(plotdata)))]);
                    chandle=colorbar(app.plotAxes);
                    chandle.Label.String=app.val_label;
                    xlim(app.plotAxes,[min(app.beam_data.raxis) max(app.beam_data.raxis)])
                    ylim(app.plotAxes,[min(app.beam_data.phiaxis) max(app.beam_data.phiaxis)])
                    if app.PlotEquilibriumCheckBox.Value
                        hold(app.plotAxes,"on");
                        s=squeeze(app.beam_data.S_ARR(:,:,app.slide_pos3))';
                        contour(app.plotAxes,app.beam_data.raxis,app.beam_data.phiaxis,s,[1 1],'r','LineWidth',2)
                        contour(app.plotAxes,app.beam_data.raxis,app.beam_data.phiaxis,s,[0.25 0.25],'--r')
                        hold(app.plotAxes,'off');
                    end
                    if app.PlotWallCheckBox.Value
                        z = app.beam_data.zaxis(app.slide_pos3);
                        surf1=[]; surf2=[];
                        surf1.vertices=app.beam_data.wall_vertex';
                        surf1.faces=app.beam_data.wall_faces';
                        xaxis = app.beam_data.raxis*cos(app.beam_data.phiaxis');
                        yaxis = app.beam_data.raxis*sin(app.beam_data.phiaxis');
                        xmin = min(min(xaxis)); xmax = max(max(xaxis));
                        ymin = min(min(yaxis)); ymax = max(max(yaxis));
                        surf2.vertices=[xmin ymin z; xmax ymin z;...
                            xmin ymax z; xmax ymax z];
                        surf2.faces = [1 2 3; 3 2 4];
                        [~, intSurface] = SurfaceIntersection(surf1, surf2);
                        if ~isempty(intSurface.vertices)
                            x2 = intSurface.vertices(:,1);
                            y2 = intSurface.vertices(:,2);
                            r2 = sqrt(x2.*x2+y2.*y2);
                            p2 = atan2(y2,x2);
                            hold(app.plotAxes,"on");
                            plot(app.plotAxes,r2(intSurface.edges'),p2(intSurface.edges'),'w')
                            hold(app.plotAxes,"off");
                        end
                    end
                case 'PHI/Z'
                    r = app.beam_data.raxis(app.slide_pos1);
                    pcolor(app.plotAxes,app.beam_data.phiaxis,app.beam_data.zaxis,squeeze(plotdata(app.slide_pos1,:,:))');
                    app.plotAxes.Children.EdgeColor = 'none';
                    colormap(app.plotAxes,'jet')
                    ylabel(app.plotAxes,'Z [m]');
                    xlabel(app.plotAxes,'\phi [rad]');
                    title(app.plotAxes,[app.plot_title ' (R = ' num2str(r,'%5.2f') ' m)'])
                    axis(app.plotAxes,'tight');
                    caxis(app.plotAxes,[min(min(min(plotdata))) max(max(max(plotdata)))]);
                    chandle=colorbar(app.plotAxes);
                    chandle.Label.String=app.val_label;
                    if app.PlotEquilibriumCheckBox.Value
                        hold(app.plotAxes,"on");
                        s=squeeze(app.beam_data.S_ARR(app.slide_pos1,:,:))';
                        contour(app.plotAxes,app.beam_data.phiaxis,app.beam_data.zaxis,s,[1 1],'r','LineWidth',2)
                        contour(app.plotAxes,app.beam_data.phiaxis,app.beam_data.zaxis,s,[0.25 0.25],'--r')
                        hold(app.plotAxes,'off');
                    end
                    if app.PlotWallCheckBox.Value
                        surf1=[]; surf2=[];
                        x = app.beam_data.wall_vertex(:,1);
                        y = app.beam_data.wall_vertex(:,2);
                        z = app.beam_data.wall_vertex(:,3);
                        r2 = sqrt(x.*x+y.*y);
                        p = atan2(y,x);
                        surf1.vertices(1,:) = r2;
                        surf1.vertices(2,:) = p;
                        surf1.vertices(3,:) = z;
                        surf1.faces=app.beam_data.wall_faces';
                        zmin = min(app.beam_data.zaxis); zmax = max(app.beam_data.zaxis);
                        pmin = min(app.beam_data.phiaxis); pmax = max(app.beam_data.phiaxis);
                        surf2.vertices=[r pmin zmin; r pmax zmin;...
                            r pmin zmax; r pmax zmax];
                        surf2.faces = [1 2 3; 3 2 4];
                        [~, intSurface] = SurfaceIntersection(surf1, surf2);
                        if ~isempty(intSurface.vertices)
                            y2 = intSurface.vertices(:,2);
                            z2 = intSurface.vertices(:,3);
                            hold(app.plotAxes,"on");
                            plot(app.plotAxes,y2(intSurface.edges'),z2(intSurface.edges'),'w')
                            hold(app.plotAxes,"off");
                        end
                    end
                case 'XYZ'
                    [x, y, z] = meshgrid(app.beam_data.phiaxis,app.beam_data.raxis,app.beam_data.zaxis);
                    vmin=min(min(min(plotdata)));
                    vmax=max(max(max(plotdata)));
                    v = 0.01.*app.slide_pos1.*(vmax-vmin)+vmin;
                    fv = isosurface(x,y,z,plotdata,v);
                    r = fv.vertices(:,2);
                    ph = fv.vertices(:,1);
                    x2 = r.*cos(ph);
                    y2 = r.*sin(ph);
                    fv.vertices(:,1)=x2;
                    fv.vertices(:,2)=y2;
                    p=patch(app.plotAxes,fv);
                    xlabel(app.plotAxes,'X [m]');
                    ylabel(app.plotAxes,'Y [m]');
                    zlabel(app.plotAxes,'Z [m]');
                    title(app.plotAxes,[app.plot_title ' (val = ' num2str(v,'%5.2f') ')'])
                    p.FaceColor='green';
                    p.EdgeColor='none';
                    p.FaceLighting = 'gouraud';
                    if app.PlotEquilibriumCheckBox.Value
                        fv2 = isosurface(x,y,z,app.beam_data.S_ARR,1.0);
                        r2 = fv2.vertices(:,2);
                        ph2 = fv2.vertices(:,1);
                        fv2.vertices(:,1)=r2.*cos(ph2);
                        fv2.vertices(:,2)=r2.*sin(ph2);
                        hold(app.plotAxes,"on");
                        p2=patch(app.plotAxes,fv2);
                        p2.FaceColor='red';
                        p2.EdgeColor='none';
                        p2.FaceAlpha=0.5;
                        p2.FaceLighting = 'gouraud';
                        fv3 = isosurface(x,y,z,app.beam_data.S_ARR,0.25);
                        r3 = fv3.vertices(:,2);
                        ph3 = fv3.vertices(:,1);
                        fv3.vertices(:,1)=r3.*cos(ph3);
                        fv3.vertices(:,2)=r3.*sin(ph3);
                        hold(app.plotAxes,"on");
                        p3=patch(app.plotAxes,fv3);
                        p3.FaceColor='red';
                        p3.EdgeColor='none';
                        p3.FaceAlpha=0.5;
                        p3.FaceLighting = 'gouraud';
                        hold(app.plotAxes,'off');
                    end
                    camlight(app.plotAxes,'headlight');
                    axis(app.plotAxes,'equal');
                    rotate3d(app.plotAxes,'on')
                    view(app.plotAxes,3);
                    zoom(app.plotAxes,'off');
                case{'RHO'} % Explictly hande profiles
                    switch app.PlotDropDown.Value
                        case{'TE'}
                            plot(app.plotAxes,sqrt(app.beam_data.s1d),app.beam_data.te1d.*1E-3,'k')
                            xlabel(app.plotAxes,'r/a');
                            ylabel(app.plotAxes,'Temperature [keV]');
                            title(app.plotAxes,app.plot_title);
                        case{'TI'}
                            plot(app.plotAxes,sqrt(app.beam_data.s1d),app.beam_data.ti1d.*1E-3,'k')
                            xlabel(app.plotAxes,'r/a');
                            ylabel(app.plotAxes,'Temperature [keV]');
                            title(app.plotAxes,app.plot_title);
                        case{'NE'}
                            plot(app.plotAxes,sqrt(app.beam_data.s1d),app.beam_data.ne1d.*1E-19,'k')
                            xlabel(app.plotAxes,'r/a');
                            ylabel(app.plotAxes,'Density x10^{19} [m^{-3}]');
                            title(app.plotAxes,app.plot_title);
                        case{'ZEFF_ARR'}
                            plot(app.plotAxes,sqrt(app.beam_data.s1d),app.beam_data.zeff1d,'k')
                            xlabel(app.plotAxes,'r/a');
                            ylabel(app.plotAxes,'Z_{effective}');
                            title(app.plotAxes,app.plot_title);
                            
                    end
            end
        end
        
        function plot_cut_markers(app)
            colorbar(app.plotAxes,'off');
            rotate3d(app.plotAxes,'off');
            zoom(app.plotAxes,'on');
            view(app.plotAxes,2);
            cla(app.plotAxes);
            axis(app.plotAxes,'normal');
            dex=false(1,app.beam_data.nparticles);
            tstep = app.slide_pos4;
            for i=app.PopulationBox.Value
                dex(app.beam_data.Beam==i) = 1;
            end
            %dext = and(dex,app.beam_data.end_state'==1);
            %dexl = and(dex,app.beam_data.end_state'==2);
            %dex = and(dex,app.beam_data.end_state'==0);
            switch app.cut_type
                case 'RHO'
                    x=0:0.01:1;
                    c=(x(1:end-1)+x(2:end)).*0.5;
                    f = app.beam_data.S_lines(tstep,dex);
                    f = f(f<1);
                    [temp,~]=histcounts(sqrt(abs(f)),x);
                    plot(app.plotAxes,c,temp,'k');
                    xlabel(app.plotAxes,'r/a');
                    ylabel(app.plotAxes,'Markers');
                    title(app.plotAxes,'Radial Marker Density Evolution');
                    xlim(app.plotAxes,[0 1]);
                    ylim(app.plotAxes,[0 max(temp).*1.2]);
                case 'R/Z'
                    % Treat slide2 as over full torus
                    phiax = 0:2.*pi/double(app.beam_data.nphi-1):2*pi;
                    dphi = diff(phiax(1:2));
                    phi = phiax(app.slide_pos2);
                    phi1 = mod(phi+dphi,2.*pi);
                    phi0 = min(phi,phi1);
                    phi1 = max(phi,phi1);
                    P_lines=mod(app.beam_data.PHI_lines(tstep,dex),2.*pi);
                    dex2 = and(P_lines>=phi0,P_lines<=phi1);
                    plot(app.plotAxes,app.beam_data.R_lines(tstep,dex2),app.beam_data.Z_lines(tstep,dex2),'.')
                    xlabel(app.plotAxes,'R [m]');
                    ylabel(app.plotAxes,'Z [m]');
                    title(app.plotAxes,app.plot_title)
                    xlim(app.plotAxes,[min(app.beam_data.raxis) max(app.beam_data.raxis)]);
                    ylim(app.plotAxes,[min(app.beam_data.zaxis) max(app.beam_data.zaxis)]);
                    if app.PlotEquilibriumCheckBox.Value
                        hold(app.plotAxes,"on");
                        s=squeeze(app.beam_data.S_ARR(:,app.slide_pos2,:))';
                        contour(app.plotAxes,app.beam_data.raxis,app.beam_data.zaxis,s,[1 1],'r','LineWidth',2)
                        contour(app.plotAxes,app.beam_data.raxis,app.beam_data.zaxis,s,[0.25 0.25],'--r')
                        hold(app.plotAxes,'off');
                    end
                    if app.PlotWallCheckBox.Value
                        surf1=[]; surf2=[];
                        surf1.vertices=app.beam_data.wall_vertex';
                        surf1.faces=app.beam_data.wall_faces';
                        rmin = min(app.beam_data.raxis); rmax = max(app.beam_data.raxis);
                        zmin = min(app.beam_data.zaxis); zmax = max(app.beam_data.zaxis);
                        surf2.vertices=[rmin.*cos(phi) rmin.*sin(phi) zmax; rmax.*cos(phi) rmax.*sin(phi) zmax;...
                            rmin.*cos(phi) rmin.*sin(phi) zmin; rmax.*cos(phi) rmax.*sin(phi) zmin];
                        surf2.faces = [1 2 3; 3 2 4];
                        [~, intSurface] = SurfaceIntersection(surf1, surf2);
                        x2 = intSurface.vertices(:,1);
                        y2 = intSurface.vertices(:,2);
                        z2 = intSurface.vertices(:,3);
                        r2 = sqrt(x2.*x2+y2.*y2);
                        hold(app.plotAxes,"on");
                        plot(app.plotAxes,r2(intSurface.edges'),z2(intSurface.edges'),'k')
                        hold(app.plotAxes,"off");
                    end
                case 'RHO/U'
                    th = 0:360;
                    rho = app.beam_data.S_lines(tstep,dex);
                    rho(rho<=0) = 1;
                    rho = sqrt(rho);
                    x=rho.*cos(app.beam_data.U_lines(tstep,dex));
                    y=rho.*sin(app.beam_data.U_lines(tstep,dex));
                    plot(app.plotAxes,x,y,'.')
                    hold(app.plotAxes,"on");
                    plot(app.plotAxes,cosd(th),sind(th),'k','LineWidth',2);
                    plot(app.plotAxes,0.5.*cosd(th),0.5.*sind(th),'--k');
                    hold(app.plotAxes,"off");
                    xlim(app.plotAxes,[-1.05 1.05]);
                    ylim(app.plotAxes,[-1.05 1.05]);
                    title(app.plotAxes,app.plot_title)
                case 'VLL/VPERP'
                    plot(app.plotAxes,app.beam_data.vll_lines(tstep,dex).*1E-6,app.beam_data.vperp(tstep,dex).*1E-6,'.')
                    xlabel(app.plotAxes,'V_{para} x10^6 [m/s]');
                    ylabel(app.plotAxes,'V_{perp} x10^6 [m/s]');
                    xlim(app.plotAxes,[-1.05 1.05].*app.maxv*1E-6);
                    ylim(app.plotAxes,[0 1.05].*app.maxv.*1E-6);
                    title(app.plotAxes,[app.plot_title '(POP:' num2str(app.PopulationBox.Value,'%i,') ' TDEX:',num2str(app.slide_pos4,'%i') ')'])
                case 'XYZ'
                    plot3(app.plotAxes,app.beam_data.X_lines(tstep,dex),app.beam_data.Y_lines(tstep,dex),app.beam_data.Z_lines(tstep,dex),'.')
                    xlim(app.plotAxes,[-max(app.beam_data.raxis) max(app.beam_data.raxis)]);
                    ylim(app.plotAxes,[-max(app.beam_data.raxis) max(app.beam_data.raxis)]);
                    zlim(app.plotAxes,[min(app.beam_data.zaxis) max(app.beam_data.zaxis)]);
                    xlabel(app.plotAxes,'X [m]');
                    ylabel(app.plotAxes,'Y [m]');
                    zlabel(app.plotAxes,'Z [m]');
                    title(app.plotAxes,app.plot_title)
                    axis(app.plotAxes,'equal');
                    rotate3d(app.plotAxes,'on')
                    view(app.plotAxes,3);
                    zoom(app.plotAxes,'off');
                    if app.PlotEquilibriumCheckBox.Value
                        [x, y, z] = meshgrid(app.beam_data.phiaxis,app.beam_data.raxis,app.beam_data.zaxis);
                        fv2 = isosurface(x,y,z,app.beam_data.S_ARR,0.99);
                        r2 = fv2.vertices(:,2);
                        ph2 = fv2.vertices(:,1);
                        fv2.vertices(:,1)=r2.*cos(ph2);
                        fv2.vertices(:,2)=r2.*sin(ph2);
                        hold(app.plotAxes,"on");
                        p2=patch(app.plotAxes,fv2);
                        p2.FaceColor='red';
                        p2.EdgeColor='none';
                        p2.FaceAlpha=0.5;
                        p2.FaceLighting = 'gouraud';
                        fv3 = isosurface(x,y,z,app.beam_data.S_ARR,0.25);
                        r3 = fv3.vertices(:,2);
                        ph3 = fv3.vertices(:,1);
                        fv3.vertices(:,1)=r3.*cos(ph3);
                        fv3.vertices(:,2)=r3.*sin(ph3);
                        hold(app.plotAxes,"on");
                        p3=patch(app.plotAxes,fv3);
                        p3.FaceColor='red';
                        p3.EdgeColor='none';
                        p3.FaceAlpha=0.5;
                        p3.FaceLighting = 'gouraud';
                        hold(app.plotAxes,'off');
                    end
            end
        end
        
        function plot_cut_particles(app)
            colorbar(app.plotAxes,'off');
            rotate3d(app.plotAxes,'off');
            zoom(app.plotAxes,'on');
            view(app.plotAxes,2);
            cla(app.plotAxes);
            axis(app.plotAxes,'normal');
            dex=false(1,app.beam_data.nparticles);
            tstep = app.slide_pos4;
            for i=app.PopulationBox.Value
                dex(app.beam_data.Beam==i) = 1;
            end
            switch app.cut_type
                case 'RHO'
                    x=0:0.01:1;
                    c=(x(1:end-1)+x(2:end)).*0.5;
                    f = app.beam_data.S_lines(tstep,dex);
                    w = app.beam_data.Weight(dex);
                    w = w(f<1);
                    f = sqrt(f(f<1));
                    temp = zeros(1,length(x)-1);
                    for l = 1:length(x)-1
                        dexl = and(f>=x(l),f<x(l+1));
                        temp(l) = sum(w(dexl));
                    end
                    %[temp,~]=histcounts(sqrt(f),x);
                    plot(app.plotAxes,c,temp);
                    xlabel(app.plotAxes,'r/a');
                    ylabel(app.plotAxes,'Particles');
                    title(app.plotAxes,'Radial Particle Density');
                    xlim(app.plotAxes,[0 1]);
                    ylim(app.plotAxes,[0 max(temp).*1.2]);
                case 'R/Z'
                    plot(app.plotAxes,app.beam_data.R_lines(tstep,dex),app.beam_data.Z_lines(tstep,dex),'.')
                    xlabel(app.plotAxes,'R [m]');
                    ylabel(app.plotAxes,'Z [m]');
                    title(app.plotAxes,app.plot_title)
                    xlim(app.plotAxes,[min(app.beam_data.raxis) max(app.beam_data.raxis)]);
                    ylim(app.plotAxes,[min(app.beam_data.zaxis) max(app.beam_data.zaxis)]);
                    if isfield(app.beam_data,'S_ARR')
                        hold(app.plotAxes,"on");
                        s=squeeze(app.beam_data.S_ARR(:,app.slide_pos2,:))';
                        contour(app.plotAxes,app.beam_data.raxis,app.beam_data.zaxis,s,[1 1],'k','LineWidth',2)
                        contour(app.plotAxes,app.beam_data.raxis,app.beam_data.zaxis,s,[0.25 0.25],'--k')
                        hold(app.plotAxes,'off');
                    end
                case 'RHO/U'
                    th = 0:360;
                    r = app.beam_data.R_lines(tstep,dex);
                    dex(r<=0) = 0;
                    weight = app.beam_data.Weight(dex);
                    rho = app.beam_data.S_lines(tstep,dex);
                    rho = sqrt(rho);
                    x=rho.*cos(app.beam_data.U_lines(tstep,dex));
                    y=rho.*sin(app.beam_data.U_lines(tstep,dex));
                    edges_x = -1.01:0.01:1.01;
                    edges_y = -1.01:0.01:1.01;
                    centers_x = 0.5.*(edges_x(1:end-1)+edges_x(2:end));
                    centers_y = 0.5.*(edges_y(1:end-1)+edges_y(2:end));
                    N1=zeros(length(edges_x)-1,length(edges_y)-1);
                    for i=1:length(edges_x)-1
                        for j = 1:length(edges_y)-1
                            dexa = and(x >= edges_x(i), x<edges_x(i+1));
                            dexb = and(y >= edges_y(j), y<edges_y(j+1));
                            dexc  = and(dexa,dexb);
                            if isempty(dexc), continue; end
                            N1(i,j) = sum(weight(dexc));
                        end
                    end
                    ha=pcolor(app.plotAxes,centers_x,centers_y,N1');
                    set(ha,'EdgeColor','none');
                    
                    
                    %plot(app.plotAxes,x,y,'.')
                    hold(app.plotAxes,"on");
                    plot(app.plotAxes,cosd(th),sind(th),'w','LineWidth',2);
                    plot(app.plotAxes,0.5.*cosd(th),0.5.*sind(th),'--w');
                    hold(app.plotAxes,"off");
                    xlim(app.plotAxes,[-1.05 1.05]);
                    ylim(app.plotAxes,[-1.05 1.05]);
                    title(app.plotAxes,[app.plot_title '(POP:' num2str(app.PopulationBox.Value,'%i,') ' TDEX:',num2str(app.slide_pos4,'%i') ')'])
                case 'VLL/VPERP'
                    r = app.beam_data.R_lines(tstep,dex);
                    dex(r<=0) = 0;
                    weight = app.beam_data.Weight(dex);
                    vll = app.beam_data.vll_lines(tstep,dex);
                    vperp = app.beam_data.vperp(tstep,dex);
                    edges_vll = (-1.05:0.025:1.05).*app.maxv;
                    edges_vperp = (0:0.025:1.05).*app.maxv;
                    centers_vll = 0.5.*(edges_vll(1:end-1)+edges_vll(2:end));
                    centers_vperp = 0.5.*(edges_vperp(1:end-1)+edges_vperp(2:end));
                    N1=zeros(length(edges_vll)-1,length(edges_vperp)-1);
                    for i=1:length(edges_vll)-1
                        for j = 1:length(edges_vperp)-1
                            dexa = and(vll >= edges_vll(i), vll<edges_vll(i+1));
                            dexb = and(vperp >= edges_vperp(j), vperp<edges_vperp(j+1));
                            dexc  = and(dexa,dexb);
                            if isempty(dexc), continue; end
                            N1(i,j) = sum(weight(dexc));
                        end
                    end
                    ha=pcolor(app.plotAxes,centers_vll./1E6,centers_vperp./1E6,N1');
                    set(ha,'EdgeColor','none');
                    xlabel(app.plotAxes,'V_{para} x10^6 [m/s]');
                    ylabel(app.plotAxes,'V_{perp} x10^6 [m/s]');
                    xlim(app.plotAxes,[-1.05 1.05].*app.maxv*1E-6);
                    ylim(app.plotAxes,[0 1.05].*app.maxv.*1E-6);
                    %caxis(app.plotAxes,[0 2E19]);
                    title(app.plotAxes,[app.plot_title '(POP:' num2str(app.PopulationBox.Value,'%i,') ' TDEX:',num2str(app.slide_pos4,'%i') ')'])
                case 'XYZ'
                    plot3(app.plotAxes,app.beam_data.X_lines(tstep,dex),app.beam_data.Y_lines(tstep,dex),app.beam_data.Z_lines(tstep,dex),'.')
                    xlim(app.plotAxes,[-max(app.beam_data.raxis) max(app.beam_data.raxis)]);
                    ylim(app.plotAxes,[-max(app.beam_data.raxis) max(app.beam_data.raxis)]);
                    zlim(app.plotAxes,[min(app.beam_data.zaxis) max(app.beam_data.zaxis)]);
                    xlabel(app.plotAxes,'X [m]');
                    ylabel(app.plotAxes,'Y [m]');
                    zlabel(app.plotAxes,'Z [m]');
                    title(app.plotAxes,app.plot_title)
                    axis(app.plotAxes,'equal');
                    rotate3d(app.plotAxes,'on')
                    view(app.plotAxes,3);
                    zoom(app.plotAxes,'off');
                    if app.PlotEquilibriumCheckBox.Value
                        [x, y, z] = meshgrid(app.beam_data.phiaxis,app.beam_data.raxis,app.beam_data.zaxis);
                        fv2 = isosurface(x,y,z,app.beam_data.S_ARR,0.99);
                        r2 = fv2.vertices(:,2);
                        ph2 = fv2.vertices(:,1);
                        fv2.vertices(:,1)=r2.*cos(ph2);
                        fv2.vertices(:,2)=r2.*sin(ph2);
                        hold(app.plotAxes,"on");
                        p2=patch(app.plotAxes,fv2);
                        p2.FaceColor='red';
                        p2.EdgeColor='none';
                        p2.FaceAlpha=0.5;
                        p2.FaceLighting = 'gouraud';
                        fv3 = isosurface(x,y,z,app.beam_data.S_ARR,0.25);
                        r3 = fv3.vertices(:,2);
                        ph3 = fv3.vertices(:,1);
                        fv3.vertices(:,1)=r3.*cos(ph3);
                        fv3.vertices(:,2)=r3.*sin(ph3);
                        hold(app.plotAxes,"on");
                        p3=patch(app.plotAxes,fv3);
                        p3.FaceColor='red';
                        p3.EdgeColor='none';
                        p3.FaceAlpha=0.5;
                        p3.FaceLighting = 'gouraud';
                        hold(app.plotAxes,'off');
                    end
            end
        end
        
        function plot_cut_evoltuion(app)
            colorbar(app.plotAxes,'off');
            rotate3d(app.plotAxes,'off');
            zoom(app.plotAxes,'on');
            view(app.plotAxes,2);
            cla(app.plotAxes);
            axis(app.plotAxes,'normal');
            %dex = app.beam_data.end_state==0;
            %t_end_orbit=app.beam_data.t_end(dex);
            dex = app.beam_data.end_state==1;
            t_end_therm=app.beam_data.t_end(dex);
            dex = app.beam_data.end_state==2;
            t_end_wall=app.beam_data.t_end(dex);
            tmax = max(app.beam_data.t_end);
            x_t=0:1E-7:tmax;
            %y_orbit = histcounts(t_end_orbit,x_t);
            y_therm = histcounts(t_end_therm,x_t);
            y_wall = histcounts(t_end_wall,x_t);
            x_plot=0.5.*(x_t(1:end-1)+x_t(2:end));
            plot(app.plotAxes,log10(x_plot),1-cumsum(y_therm+y_wall)./double(app.beam_data.nparticles),'k')
            hold(app.plotAxes,"on");
            plot(app.plotAxes,log10(x_plot),cumsum(y_therm)./double(app.beam_data.nparticles),'b')
            plot(app.plotAxes,log10(x_plot),cumsum(y_wall)./double(app.beam_data.nparticles),'r')
            hold(app.plotAxes,"off");
            xlim(app.plotAxes,[-7 ceil(log10(tmax))])
            ylim(app.plotAxes,[0 1])
            xlabel(app.plotAxes,'Time log_{10} [s]');
            ylabel(app.plotAxes,'Fraction');
            legend(app.plotAxes,{'Total','Thermalized','Lost'})
        end
        
        function plot_cut_loss(app)
            colorbar(app.plotAxes,'off');
            rotate3d(app.plotAxes,'on');
            zoom(app.plotAxes,'off');
            view(app.plotAxes,3);
            cla(app.plotAxes);
            axis(app.plotAxes,'normal');
            dex = app.beam_data.end_state==2;
            idex=1;
            s = app.beam_data.S_lines(idex,dex);
            vll = app.beam_data.vll_lines(idex,dex);
            vperp = app.beam_data.vperp(idex,dex);
            v2 = vll.^2+vperp.^2;
            pitch = vll./sqrt(v2);
            E = 0.5.*app.beam_data.mass(dex)'.*v2./1.60217662E-19;
            plot3(app.plotAxes,sqrt(s),E,pitch,'.k');
            xlim(app.plotAxes,[0 1.05])
            ylim(app.plotAxes,[0 1.2.*max(E)])
            zlim(app.plotAxes,[-1.05 1.05])
            xlabel(app.plotAxes,'r/a');
            ylabel(app.plotAxes,'Energy');
            zlabel(app.plotAxes,'V_{\parallel}/V');
        end
        
        function plot_cut_orbits(app)
            colorbar(app.plotAxes,'off');
            rotate3d(app.plotAxes,'off');
            zoom(app.plotAxes,'on');
            view(app.plotAxes,2);
            cla(app.plotAxes);
            axis(app.plotAxes,'normal');
            dex = round(app.slide_pos5);
            tend = find(app.beam_data.R_lines(:,dex)<=0,1)-1;
            if isempty(tend)
                tend = length(app.beam_data.R_lines(:,dex));
            end
            tstep=1:min(tend,double(app.beam_data.nsteps));
            colormap(app.plotAxes,'jet')
            switch app.cut_type
                case 'RHO'
                    f = sqrt(abs(app.beam_data.S_lines(tstep,dex)));
                    fm = min(f);
                    fp = max(f);
                    plot(app.plotAxes,tstep,f,'k');
                    xlabel(app.plotAxes,'Timestep');
                    ylabel(app.plotAxes,'r/a');
                    title(app.plotAxes,'Radial Marker Density Evolution');
                    xlim(app.plotAxes,[0 tstep(end)]);
                    ylim(app.plotAxes,[max(0,fm*0.8) fp*1.2]);
                case 'R/Z'
                    plot(app.plotAxes,app.beam_data.R_lines(tstep,dex),app.beam_data.Z_lines(tstep,dex),'k')
                    xlabel(app.plotAxes,'R [m]');
                    ylabel(app.plotAxes,'Z [m]');
                    xlim(app.plotAxes,[min(app.beam_data.raxis) max(app.beam_data.raxis)]);
                    ylim(app.plotAxes,[min(app.beam_data.zaxis) max(app.beam_data.zaxis)]);
                    if isfield(app.beam_data,'S_ARR')
                        hold(app.plotAxes,"on");
                        s=squeeze(app.beam_data.S_ARR(:,app.slide_pos2,:))';
                        contour(app.plotAxes,app.beam_data.raxis,app.beam_data.zaxis,s,[1 1],'k','LineWidth',2)
                        contour(app.plotAxes,app.beam_data.raxis,app.beam_data.zaxis,s,[0.25 0.25],'--k')
                        hold(app.plotAxes,'off');
                    end
                case 'RHO/U'
                    th = 0:360;
                    rho = app.beam_data.S_lines(tstep,dex);
                    rho(rho<=0) = 1;
                    rho = sqrt(rho);
                    x=rho.*cos(app.beam_data.U_lines(tstep,dex));
                    y=rho.*sin(app.beam_data.U_lines(tstep,dex));
                    scatter(app.plotAxes,x,y,x.*0+1,tstep,'filled')
                    %plot(app.plotAxes,x,y,'.k')
                    hold(app.plotAxes,"on");
                    plot(app.plotAxes,cosd(th),sind(th),'k','LineWidth',2);
                    plot(app.plotAxes,0.5.*cosd(th),0.5.*sind(th),'--k');
                    plot(app.plotAxes,0,0,'+k');
                    hold(app.plotAxes,"off");
                    xlim(app.plotAxes,[-1.05 1.05]);
                    ylim(app.plotAxes,[-1.05 1.05]);
                case 'VLL/VPERP'
                    x=app.beam_data.vll_lines(tstep,dex).*1E-6;
                    y=app.beam_data.vperp(tstep,dex).*1E-6;
                    scatter(app.plotAxes,x,y,x.*0+1,tstep,'filled')
                    %plot(app.plotAxes,app.beam_data.vll_lines(tstep,dex).*1E-6,app.beam_data.vperp(tstep,dex).*1E-6,'.k')
                    xlabel(app.plotAxes,'V_{para} x10^6 [m/s]');
                    ylabel(app.plotAxes,'V_{perp} x10^6 [m/s]');
                    xlim(app.plotAxes,[-1.05 1.05].*app.maxv*1E-6);
                    ylim(app.plotAxes,[0 1.05].*app.maxv.*1E-6);
                case 'XYZ'
                    x=app.beam_data.X_lines(tstep,dex);
                    y=app.beam_data.Y_lines(tstep,dex);
                    z=app.beam_data.Z_lines(tstep,dex);
                    scatter3(app.plotAxes,x,y,z,x*0+1,tstep,'filled');
                    %plot3(app.plotAxes,app.beam_data.X_lines(tstep,dex),app.beam_data.Y_lines(tstep,dex),app.beam_data.Z_lines(tstep,dex),'k')
                    xlim(app.plotAxes,[-max(app.beam_data.raxis) max(app.beam_data.raxis)]);
                    ylim(app.plotAxes,[-max(app.beam_data.raxis) max(app.beam_data.raxis)]);
                    zlim(app.plotAxes,[min(app.beam_data.zaxis) max(app.beam_data.zaxis)]);
                    xlabel(app.plotAxes,'X [m]');
                    ylabel(app.plotAxes,'Y [m]');
                    zlabel(app.plotAxes,'Z [m]');
                    axis(app.plotAxes,'equal');
                    rotate3d(app.plotAxes,'on')
                    view(app.plotAxes,3);
                    zoom(app.plotAxes,'off');
                    if app.PlotEquilibriumCheckBox.Value
                        [x, y, z] = meshgrid(app.beam_data.phiaxis,app.beam_data.raxis,app.beam_data.zaxis);
                        fv2 = isosurface(x,y,z,app.beam_data.S_ARR,0.99);
                        r2 = fv2.vertices(:,2);
                        ph2 = fv2.vertices(:,1);
                        fv2.vertices(:,1)=r2.*cos(ph2);
                        fv2.vertices(:,2)=r2.*sin(ph2);
                        hold(app.plotAxes,"on");
                        p2=patch(app.plotAxes,fv2);
                        p2.FaceColor='red';
                        p2.EdgeColor='none';
                        p2.FaceAlpha=0.5;
                        p2.FaceLighting = 'gouraud';
                        fv3 = isosurface(x,y,z,app.beam_data.S_ARR,0.25);
                        r3 = fv3.vertices(:,2);
                        ph3 = fv3.vertices(:,1);
                        fv3.vertices(:,1)=r3.*cos(ph3);
                        fv3.vertices(:,2)=r3.*sin(ph3);
                        hold(app.plotAxes,"on");
                        p3=patch(app.plotAxes,fv3);
                        p3.FaceColor='red';
                        p3.EdgeColor='none';
                        p3.FaceAlpha=0.5;
                        p3.FaceLighting = 'gouraud';
                        hold(app.plotAxes,'off');
                    end
            end
            title(app.plotAxes,[app.plot_title '(PART:' num2str(app.slide_pos5,'%i') ')'])
        end
        
        function plot_cut_dist(app,plotdata)
            colorbar(app.plotAxes,'off');
            rotate3d(app.plotAxes,'off');
            zoom(app.plotAxes,'on');
            view(app.plotAxes,2);
            cla(app.plotAxes);
            axis(app.plotAxes,'normal');
            switch app.cut_type
                case 'RHO'
                    plot(app.plotAxes,app.beam_data.rho,plotdata(app.PopulationBox.Value,:))
                    xlabel(app.plotAxes,'r/a');
                    ylabel(app.plotAxes,app.val_label)
                    title(app.plotAxes,app.plot_title)
                    axis(app.plotAxes,'tight');
            end
        end
        
        function update_sliders(app)
            % Default look
            app.PlotWallCheckBox.Enable=1;
            app.PlotEquilibriumCheckBox.Enable=1;
            app.TimestepSlider.Enable=0;
            app.ParticleSlider.Enable=0;
            app.Slider1.Limits=[1 2];
            app.Slider2.Limits=[1 2];
            app.Slider3.Limits=[1 2];
            app.Slider1.MajorTicks=[];
            app.Slider2.MajorTicks=[];
            app.Slider3.MajorTicks=[];
            app.Slider1.MajorTickLabels={};
            app.Slider2.MajorTickLabels={};
            app.Slider3.MajorTickLabels={};
            % We handle changing cuttype here
            if contains(app.PlotDropDown.Value,'WALL')
                app.cut_type='XYZ';
                app.XYZButton.Value=1;
            end
            if contains(app.PlotDropDown.Value,'MARKERS')
                app.TimestepSlider.Enable=1;
            end
            if contains(app.PlotDropDown.Value,'PARTICLES')
                app.TimestepSlider.Enable=1;
            end
            if contains(app.PlotDropDown.Value,'ORBITS')
                app.ParticleSlider.Enable=1;
                app.ParticleSlider.Limits=[1 double(app.beam_data.nparticles)];
            end
            % Set enable/disable
            switch app.cut_type
                case 'R'
                    app.Slider1.Enable = 0;
                    app.Slider2.Enable = 1;
                    app.Slider3.Enable = 1;
                    app.PlotEquilibriumCheckBox.Enable=0;
                    app.PlotWallCheckBox.Enable=0;
                case 'PHI'
                    app.Slider1.Enable = 1;
                    app.Slider2.Enable = 0;
                    app.Slider3.Enable = 1;
                    app.PlotEquilibriumCheckBox.Enable=0;
                    app.PlotWallCheckBox.Enable=0;
                case 'Z'
                    app.Slider1.Enable = 1;
                    app.Slider2.Enable = 1;
                    app.Slider3.Enable = 0;
                    app.PlotEquilibriumCheckBox.Enable=0;
                    app.PlotWallCheckBox.Enable=0;
                case 'RHO'
                    app.Slider1.Enable = 0;
                    app.Slider2.Enable = 0;
                    app.Slider3.Enable = 0;
                    app.PlotEquilibriumCheckBox.Enable=0;
                    app.PlotWallCheckBox.Enable=0;
                case 'R/Z'
                    app.Slider1.Enable = 0;
                    app.Slider2.Enable = 1;
                    app.Slider3.Enable = 0;
                case 'PHI/R'
                    app.Slider1.Enable = 0;
                    app.Slider2.Enable = 0;
                    app.Slider3.Enable = 1;
                case 'PHI/Z'
                    app.Slider1.Enable = 1;
                    app.Slider2.Enable = 0;
                    app.Slider3.Enable = 0;
                case {'RHO/U','VLL/VPERP'}
                    app.Slider1.Enable = 0;
                    app.Slider2.Enable = 0;
                    app.Slider3.Enable = 0;
                    app.PlotWallCheckBox.Enable=0;
                    app.PlotEquilibriumCheckBox.Enable=0;
                case 'XYZ'
                    if contains(app.PlotDropDown.Value,'WALL_MAGAXIS')
                        app.Slider1.Enable = 1;
                        app.Slider2.Enable = 1;
                        app.Slider3.Enable = 1;
                    else
                        app.Slider1.Enable = 1;
                        app.Slider2.Enable = 0;
                        app.Slider3.Enable = 0;
                    end
                    app.PlotWallCheckBox.Enable=0;
            end
            % Set Labels
            switch app.cut_type
                case {'RHO'}
                    app.Label1.Text = '';
                    app.Label2.Text = '';
                    app.Label3.Text = '';
                    app.Slider1.Limits=[1 2];
                    app.Slider2.Limits=[1 2];
                    app.Slider3.Limits=[1 2];
                case {'R','PHI','Z','R/Z','PHI/R','PHI/Z'}
                    app.Label1.Text = 'R';
                    app.Label2.Text = 'PHI';
                    app.Label3.Text = 'Z';
                    app.Slider1.Limits=[1 double(app.beam_data.nr)];
                    app.Slider2.Limits=[1 double(app.beam_data.nphi)];
                    app.Slider3.Limits=[1 double(app.beam_data.nz)];
                case {'XYZ'}
                    if contains(app.PlotDropDown.Value,'WALL_MAGAXIS')
                        app.XYZButton.Value=1;
                        app.Label1.Text = 'alpha';
                        app.Label2.Text = 'phi';
                        app.Label3.Text = 'theta';
                        app.Slider1.Limits=[-90 90];
                        app.Slider2.Limits=[0 100];
                        app.Slider3.Limits=[-180 180];
                    elseif contains(app.PlotDropDown.Value,'WALL3D')
                        app.Label1.Text = 'POP';
                        app.Label2.Text = '';
                        app.Label3.Text = '';
                        app.Slider1.Limits=[1 max(double(app.beam_data.nbeams),2)];
                        app.slide_pos2 = 1;
                        app.slide_pos3 = 1;
                    else
                        app.Label1.Text = 'VALUE';
                        app.Label2.Text = '';
                        app.Label3.Text = '';
                        app.Slider1.Limits=[0 100];
                        app.slide_pos2 = 1;
                        app.slide_pos3 = 1;
                    end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if exist('SurfaceIntersection','file')~=2
                disp(' Please download the SurfaceIntersection Routine from:')
                disp('     https://www.mathworks.com/matlabcentral/fileexchange/48613-surface-intersection');
                app.PlotWallCheckBox.Enable=0;
            end
            temp = dir("*beams3d_*.h5");
            for i = 1:length(temp)
                app.FilenameDropDown.Items{i}=temp(i).name;
            end
            app.load_beam_data;
        end

        % Value changed function: FilenameDropDown
        function load_beam_data(app, event)
            value = app.FilenameDropDown.Value;
            % Load Data
            app.StatusLabel.Text='LOADING....';
            app.beam_data = read_beams3d(value);
            if isfield(app.beam_data,'S_ARR')
                app.beam_data.RHO = sqrt(app.beam_data.S_ARR);
            end
            % VPERP
            app.StatusLabel.Text='VPERP Calc...';
            if isfield(app.beam_data,'mass')
                app.beam_data.vperp=beams3d_calc_vperp(app.beam_data);
                app.maxv = max(max(max(app.beam_data.vll_lines)),...
                    max(max(app.beam_data.vperp)));
            else
                app.maxv = 1.0;
            end
            % Fix U_lines
            app.beam_data.U_lines = beams3d_fixUlines(app.beam_data);
            % Birth rate
            x=0:1.0/app.beam_data.ns_prof1:1;
            c=(x(1:end-1)+x(2:end)).*0.5;
            if app.beam_data.lbeam
                f = app.beam_data.S_lines(2,:);
            else
                f = app.beam_data.S_lines(1,:);
            end
            w = app.beam_data.Weight(:);
            beam = app.beam_data.Beam(:)';
            endstate = app.beam_data.end_state(:)';
            f = sqrt(f);
            app.beam_data.birth_prof = zeros(app.beam_data.nbeams,length(x)-1);
            app.beam_data.loss_prof = zeros(app.beam_data.nbeams,length(x)-1);
            for j=1:app.beam_data.nbeams
                for l = 1:length(x)-1
                    dexl = and(f>=x(l),f<x(l+1));
                    dexb  = and(dexl,beam==j);
                    dexd  = and(dexl,endstate==2);
                    app.beam_data.birth_prof(j,l) = sum(w(dexb));
                    app.beam_data.loss_prof(j,l) = sum(w(dexd));
                end
            end
            [x,~,dVds] = beams3d_volume(app.beam_data);
            for j=1:app.beam_data.nbeams
                app.beam_data.birth_prof(j,:) = app.beam_data.ns_prof1.*app.beam_data.birth_prof(j,:)./pchip([0 sqrt(x)],[0 dVds.*2.*sqrt(x)],c);
                app.beam_data.loss_prof(j,:) = app.beam_data.ns_prof1.*app.beam_data.loss_prof(j,:)./pchip([0 sqrt(x)],[0 dVds.*2.*sqrt(x)],c);
            end
            % PROFILES
            app.StatusLabel.Text='Profile Calc...';
            if isfield(app.beam_data,'TE')
                [app.beam_data.s1d,...
                    app.beam_data.ne1d,...
                    app.beam_data.te1d,...
                    app.beam_data.ti1d,...
                    app.beam_data.zeff1d] = beams3d_profiles(app.beam_data);
            end
            if isfield(app.beam_data,'POT_ARR')
                [app.beam_data.pots1d,...
                    app.beam_data.pot1d,...
                    app.beam_data.Es1d] = beams3d_er(app.beam_data);
                app.beam_data.E_R = zeros(app.beam_data.nr,...
                    app.beam_data.nphi,app.beam_data.nz);
                app.beam_data.E_PHI = zeros(app.beam_data.nr,...
                    app.beam_data.nphi,app.beam_data.nz);
                app.beam_data.E_Z = zeros(app.beam_data.nr,...
                    app.beam_data.nphi,app.beam_data.nz);
                nr = app.beam_data.nr;
                nphi = app.beam_data.nphi;
                nz = app.beam_data.nz;
                %E_R
                xhalf1d = 0.5.*(app.beam_data.raxis(1:nr-1)+app.beam_data.raxis(2:nr));
                dxtemp = repmat(diff(app.beam_data.raxis,1,1),[1 nphi nz]); %dR
                ftemp = diff(app.beam_data.POT_ARR,1,1); %d(R*f)
                ftemp2 = -ftemp./dxtemp; %-(df/dR)
                app.beam_data.E_R = interp1(xhalf1d,ftemp2,app.beam_data.raxis);
                %E_PHI
                xhalf1d = 0.5.*(app.beam_data.phiaxis(1:nphi-1)+app.beam_data.phiaxis(2:nphi));
                Rhalf = permute(repmat(app.beam_data.raxis,[1 nphi-1 nz]),[2 1 3]);
                dxtemp = repmat(diff(app.beam_data.phiaxis,1,1),[1 nr nz]); %dphi
                ftemp = permute(diff(app.beam_data.POT_ARR,1,2),[2 1 3]); %d(f)
                ftemp2 = -ftemp./(dxtemp.*Rhalf); %-(df/dZ)/R
                app.beam_data.E_PHI = permute(interp1(xhalf1d,ftemp2,app.beam_data.phiaxis),[2 1 3]);
                %E_PHI
                xhalf1d = 0.5.*(app.beam_data.zaxis(1:nz-1)+app.beam_data.zaxis(2:nz));
                dxtemp = repmat(diff(app.beam_data.zaxis,1,1),[1 nphi nr]); %dR
                ftemp = permute(diff(app.beam_data.POT_ARR,1,3),[3 2 1]); %d(f)
                ftemp2 = -ftemp./dxtemp; %-(df/dZ)
                app.beam_data.E_Z = permute(interp1(xhalf1d,ftemp2,app.beam_data.zaxis),[3 2 1]);
                
            end
            % Adjust magnetic field to deal with coils
            app.StatusLabel.Text='|B| Calc...';
            app.beam_data.B = sqrt(app.beam_data.B_R.*app.beam_data.B_R + ...
                app.beam_data.B_PHI.*app.beam_data.B_PHI + ...
                app.beam_data.B_Z.*app.beam_data.B_Z);
            B_mean = mean(mean(mean(app.beam_data.B)));
            dex = app.beam_data.B>5.*B_mean;
            app.beam_data.B_R(dex)=B_mean;
            app.beam_data.B_PHI(dex)=B_mean;
            app.beam_data.B_Z(dex)=B_mean;
            app.beam_data.B = sqrt(app.beam_data.B_R.*app.beam_data.B_R + ...
                app.beam_data.B_PHI.*app.beam_data.B_PHI + ...
                app.beam_data.B_Z.*app.beam_data.B_Z);
            % Find magnetic axis
            app.StatusLabel.Text='AXIS Calc...';
            if (~app.beam_data.lvac)
                [raxis, zaxis] = beams3d_magaxis(app.beam_data);
                app.raxis_spl = pchip(app.beam_data.phiaxis,raxis);
                app.zaxis_spl = pchip(app.beam_data.phiaxis,zaxis);
            end
            % Set population
            app.poplist = unique(app.beam_data.Beam);
            app.PopulationBox.Items={};
            app.PopulationBox.ItemsData=[];
            for i = app.poplist'
                app.PopulationBox.Items{i}= ['Population ' num2str(i,'%2.2d')];
                app.PopulationBox.ItemsData(i)=i;
            end
            app.PopulationBox.Value=1;
            % Reset Slider Limits
            app.Slider1.Limits=[1 double(app.beam_data.nr)];
            app.Slider2.Limits=[1 double(app.beam_data.nphi)];
            app.Slider3.Limits=[1 double(app.beam_data.nz)];
            app.TimestepSlider.Limits=[1 double(app.beam_data.npoinc+1)];
            % Update plots accordingly
            app.update_plotlist;
            app.change_plot;
            app.StatusLabel.Text='Ready';
        end

        % Value changed function: PlotDropDown
        function change_plot(app, event)
            value = app.PlotDropDown.Value;
            app.update_sliders;
            app.update_plots(value);
        end

        % Value changed function: Slider1
        function Slider1ValueChanged(app, event)
            value = app.Slider1.Value;
            app.slide_pos1 = round(value);
            app.update_plots(app.PlotDropDown.Value);
        end

        % Value changed function: Slider2
        function Slider2ValueChanged(app, event)
            value = app.Slider2.Value;
            app.slide_pos2 = round(value);
            app.update_plots(app.PlotDropDown.Value);
        end

        % Value changed function: Slider3
        function Slider3ValueChanged(app, event)
            value = app.Slider3.Value;
            app.slide_pos3 = round(value);
            app.update_plots(app.PlotDropDown.Value);
        end

        % Selection changed function: CutsButtonGroup
        function CutsButtonGroupSelectionChanged(app, event)
            app.cut_type = app.CutsButtonGroup.SelectedObject.Text;
            app.update_sliders;
            app.update_plots(app.PlotDropDown.Value);
        end

        % Button pushed function: PlottoFileButton
        function PlottoFileButtonPushed(app, event)
            % From https://www.mathworks.com/matlabcentral/answers/281318-how-can-i-save-a-figure-within-app-designer
            filename=app.EditField.Value;
            % Create a temporary figure with axes.
            fig = figure;
            fig.Visible = 'off';
            figAxes = axes(fig);
            % Copy all UIAxes children, take over axes limits and aspect ratio.
            allChildren = app.plotAxes.XAxis.Parent.Children;
            copyobj(allChildren, figAxes)
            for field={'XLim','YLim','ZLim','DataAspectRatio','Colormap'}
                %disp(field{1})
                figAxes.(field{1}) = app.plotAxes.(field{1});
            end
            for field={'XLabel','YLabel','ZLabel','Title'}
                %disp(field{1})
                figAxes.(field{1}).String = app.plotAxes.(field{1}).String;
            end
            % Save as png and fig files.
            saveas(fig, filename);
            % Delete the temporary figure.
            delete(fig);
        end

        % Value changed function: PlotEquilibriumCheckBox
        function PlotEquilibriumCheckBoxValueChanged(app, event)
            %value = app.PlotEquilibriumCheckBox.Value;
            app.change_plot;
        end

        % Value changed function: PlotWallCheckBox
        function PlotWallCheckBoxValueChanged(app, event)
            %value = app.PlotWallCheckBox.Value;
            app.change_plot;
        end

        % Value changed function: PopulationBox
        function PopulationBoxValueChanged(app, event)
            %value = app.PopulationBox.Value;
            app.change_plot;
        end

        % Value changed function: TimestepSlider
        function TimestepSliderValueChanged(app, event)
            value = app.TimestepSlider.Value;
            app.slide_pos4 = round(value);
            app.update_plots(app.PlotDropDown.Value);
        end

        % Value changed function: ParticleSlider
        function ParticleSliderValueChanged(app, event)
            value = app.ParticleSlider.Value;
            app.slide_pos5 = round(value);
            app.update_plots(app.PlotDropDown.Value);
            
        end

        % Callback function
        function ASCOTButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.plotAxes = uiaxes(app.UIFigure);
            title(app.plotAxes, 'Title')
            xlabel(app.plotAxes, 'X')
            ylabel(app.plotAxes, 'Y')
            zlabel(app.plotAxes, 'Z')
            app.FilenameDropDownLabel = uilabel(app.UIFigure);
            app.FilenameDropDownLabel.Text = 'Filename: ';
            app.FilenameDropDown = uidropdown(app.UIFigure);
            app.FilenameDropDown.Items = {'Option 1'};
            app.FilenameDropDown.ValueChangedFcn = createCallbackFcn(app, @load_beam_data, true);
            app.BEAMS3DplotCreatedbySamuelLazersonsamuellazersonippmpgdeLabel = uilabel(app.UIFigure);
            app.BEAMS3DplotCreatedbySamuelLazersonsamuellazersonippmpgdeLabel.Text = {'BEAMS3Dplot'; 'Created by: Samuel Lazerson'; '(samuel.lazerson@ipp.mpg.de)'};
            app.Slider1 = uislider(app.UIFigure);
            app.Slider1.MajorTicks = [];
            app.Slider1.ValueChangedFcn = createCallbackFcn(app, @Slider1ValueChanged, true);
            app.Slider1.MinorTicks = [];
            app.PlotDropDownLabel = uilabel(app.UIFigure);
            app.PlotDropDownLabel.Text = 'Plot:';
            app.PlotDropDown = uidropdown(app.UIFigure);
            app.PlotDropDown.Items = {'Overview', '-----1D-----', 'RHO', 'mod(B)', 'Br', 'Bphi', 'Bz', 'E-static'};
            app.PlotDropDown.ValueChangedFcn = createCallbackFcn(app, @change_plot, true);
            app.PlotDropDown.Value = 'Overview';
            app.CutsButtonGroup = uibuttongroup(app.UIFigure);
            app.CutsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CutsButtonGroupSelectionChanged, true);
            app.CutsButtonGroup.Title = 'Cuts';
            app.RButton = uiradiobutton(app.CutsButtonGroup);
            app.RButton.Text = 'R';
            app.RButton.Value = true;
            app.PHIButton = uiradiobutton(app.CutsButtonGroup);
            app.PHIButton.Text = 'PHI';
            app.ZButton = uiradiobutton(app.CutsButtonGroup);
            app.ZButton.Text = 'Z';
            app.RZButton = uiradiobutton(app.CutsButtonGroup);
            app.RZButton.Text = 'R/Z';
            app.PHIRButton = uiradiobutton(app.CutsButtonGroup);
            app.PHIRButton.Text = 'PHI/R';
            app.PHIZButton = uiradiobutton(app.CutsButtonGroup);
            app.PHIZButton.Text = 'PHI/Z';
            app.XYZButton = uiradiobutton(app.CutsButtonGroup);
            app.XYZButton.Text = 'XYZ';
            app.RHOButton = uiradiobutton(app.CutsButtonGroup);
            app.RHOButton.Text = 'RHO';
            app.RHOUButton = uiradiobutton(app.CutsButtonGroup);
            app.RHOUButton.Text = 'RHO/U';
            app.VLLVPERPButton = uiradiobutton(app.CutsButtonGroup);
            app.VLLVPERPButton.Text = 'VLL/VPERP';
            app.PlottoFileButton = uibutton(app.UIFigure, 'push');
            app.PlottoFileButton.ButtonPushedFcn = createCallbackFcn(app, @PlottoFileButtonPushed, true);
            app.PlottoFileButton.Text = 'Plot to File';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Value = 'Filename';
            app.Slider2 = uislider(app.UIFigure);
            app.Slider2.MajorTicks = [];
            app.Slider2.ValueChangedFcn = createCallbackFcn(app, @Slider2ValueChanged, true);
            app.Slider2.MinorTicks = [];
            app.Slider3 = uislider(app.UIFigure);
            app.Slider3.MajorTicks = [];
            app.Slider3.ValueChangedFcn = createCallbackFcn(app, @Slider3ValueChanged, true);
            app.Slider3.MinorTicks = [];
            app.Label1 = uilabel(app.UIFigure);
            app.Label1.Text = 'Label1';
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = 'Label2';
            app.Label3 = uilabel(app.UIFigure);
            app.Label3.Text = 'Label3';
            app.PlotEquilibriumCheckBox = uicheckbox(app.UIFigure);
            app.PlotEquilibriumCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotEquilibriumCheckBoxValueChanged, true);
            app.PlotEquilibriumCheckBox.Text = 'Plot Equilibrium';
            app.PlotWallCheckBox = uicheckbox(app.UIFigure);
            app.PlotWallCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotWallCheckBoxValueChanged, true);
            app.PlotWallCheckBox.Text = 'Plot Wall';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Status';
            app.PopulationBox = uilistbox(app.UIFigure);
            app.PopulationBox.Items = {};
            app.PopulationBox.Multiselect = 'on';
            app.PopulationBox.ValueChangedFcn = createCallbackFcn(app, @PopulationBoxValueChanged, true);
            app.PopulationBox.Value = {};
            app.TimestepSliderLabel = uilabel(app.UIFigure);
            app.TimestepSliderLabel.Text = 'Timestep';
            app.TimestepSlider = uislider(app.UIFigure);
            app.TimestepSlider.MajorTicks = [];
            app.TimestepSlider.ValueChangedFcn = createCallbackFcn(app, @TimestepSliderValueChanged, true);
            app.TimestepSlider.MinorTicks = [];
            app.ParticleSliderLabel = uilabel(app.UIFigure);
            app.ParticleSliderLabel.Text = 'Particle';
            app.ParticleSlider = uislider(app.UIFigure);
            app.ParticleSlider.MajorTicks = [];
            app.ParticleSlider.ValueChangedFcn = createCallbackFcn(app, @ParticleSliderValueChanged, true);
            app.ParticleSlider.MinorTicks = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BEAMS3Dplot

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end